classdef dset_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        plotdataButtonGroup             matlab.ui.container.ButtonGroup
        allButton                       matlab.ui.control.ToggleButton
        mouseButton                     matlab.ui.control.ToggleButton
        dsetButton                      matlab.ui.control.ToggleButton
        TuningecdfthreshEditField       matlab.ui.control.NumericEditField
        TuningecdfthreshEditFieldLabel  matlab.ui.control.Label
        ZthreshnewEditField             matlab.ui.control.NumericEditField
        ZthreshnewEditFieldLabel        matlab.ui.control.Label
        ZthreshcurrentEditField         matlab.ui.control.NumericEditField
        ZthreshcurrentEditFieldLabel    matlab.ui.control.Label
        StatmethodDropDown              matlab.ui.control.DropDown
        StatmethodDropDownLabel         matlab.ui.control.Label
        StatsourceDropDown              matlab.ui.control.DropDown
        StatsourceDropDownLabel         matlab.ui.control.Label
        FramerateEditField              matlab.ui.control.NumericEditField
        FramerateEditFieldLabel         matlab.ui.control.Label
        baserespsEditField              matlab.ui.control.EditField
        baserespsLabel                  matlab.ui.control.Label
        MMNfreqEditField                matlab.ui.control.EditField
        MMNfreqEditFieldLabel           matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        PlotregButton                   matlab.ui.control.Button
        CombinegroupsCheckBox           matlab.ui.control.CheckBox
        PlottuningecdfButton            matlab.ui.control.Button
        RectifyspikesCheckBox           matlab.ui.control.CheckBox
        SubtractmeanspikesCheckBox      matlab.ui.control.CheckBox
        ConverttoZCheckBox              matlab.ui.control.CheckBox
        NormalizemaxspikesCheckBox      matlab.ui.control.CheckBox
        recomputestatsButton            matlab.ui.control.Button
        PlotselecttrialButton           matlab.ui.control.Button
        NewplotsCheckBox                matlab.ui.control.CheckBox
        ManualtimewinCheckBox           matlab.ui.control.CheckBox
        FullredCheckBox                 matlab.ui.control.CheckBox
        PlotctxrespButton               matlab.ui.control.Button
        PlotfreqrespButton              matlab.ui.control.Button
        RunallstatsButton               matlab.ui.control.Button
        UpdatefigsCheckBox              matlab.ui.control.CheckBox
        SmoothsigmamsEditField          matlab.ui.control.NumericEditField
        SmoothsigmamsEditFieldLabel     matlab.ui.control.Label
        SmoothCheckBox                  matlab.ui.control.CheckBox
        DeconvolutionmethodDropDown     matlab.ui.control.DropDown
        DeconvolutionmethodDropDownLabel  matlab.ui.control.Label
        CButton                         matlab.ui.control.StateButton
        UpdateButton                    matlab.ui.control.Button
        ShiftEditField_st               matlab.ui.control.NumericEditField
        ShiftEditField_3Label           matlab.ui.control.Label
        ScaleEditField_st               matlab.ui.control.NumericEditField
        ScaleEditField_3Label           matlab.ui.control.Label
        ShiftEditField_sp               matlab.ui.control.NumericEditField
        ShiftEditFieldLabel             matlab.ui.control.Label
        ScaleEditField_sp               matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        trialtypeDropDown               matlab.ui.control.DropDown
        trialtypeDropDownLabel          matlab.ui.control.Label
        StimtimesButton                 matlab.ui.control.StateButton
        LoadregdataButton               matlab.ui.control.Button
        regdatapathEditField            matlab.ui.control.EditField
        regdatapathEditFieldLabel       matlab.ui.control.Label
        SpikesButton                    matlab.ui.control.StateButton
        RawButton                       matlab.ui.control.StateButton
        CellSpinner                     matlab.ui.control.Spinner
        CellSpinnerLabel                matlab.ui.control.Label
        mplSpinner                      matlab.ui.control.Spinner
        mplSpinnerLabel                 matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetDropDownLabel            matlab.ui.control.Label
        Runs31Button                    matlab.ui.control.Button
        UIAxesColorPallet               matlab.ui.control.UIAxes
        ContourlimitsLabel              matlab.ui.control.Label
        ContourMaxEditField             matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        ContourMinEditField             matlab.ui.control.NumericEditField
        MinLabel                        matlab.ui.control.Label
        ContoursButtonGroup             matlab.ui.container.ButtonGroup
        SNRButton_5                     matlab.ui.control.ToggleButton
        OnsetoffsetButton               matlab.ui.control.ToggleButton
        TuningmagButton                 matlab.ui.control.ToggleButton
        TuningtypeButton                matlab.ui.control.ToggleButton
        NoneButton                      matlab.ui.control.ToggleButton
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        gui_ops= struct;
        data;
        ops;
        ddata;
        cdata;
        reg_data;
        working_ops;
        gui_plots;
        current_data_idx;
        current_cell_raw;
        current_cell_C;
        current_cell_spikes;
        stats;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gui_ops.gui_dir = fileparts(which('dset_viewer.mlapp'));
            addpath(app.gui_ops.gui_dir);
            addpath([app.gui_ops.gui_dir '\gui_functions']);
            addpath([app.gui_ops.gui_dir '\general_functions']);
            addpath([app.gui_ops.gui_dir '\s3_mpl_functions']);
            f_dset_viewer_ops(app);
            f_dv_initialize(app);
        end

        % Button pushed function: Runs31Button
        function Runs31ButtonPushed(app, event)
            f_dv_runs31_button(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            f_dv_random_button(app);
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            f_dv_load_dset_from_data(app);
        end

        % Value changed function: mplSpinner
        function mplSpinnerValueChanged(app, event)
            f_dv_update_dset_info(app);
            f_dv_initialize_contours(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: CellSpinner
        function CellSpinnerValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Button pushed function: LoadregdataButton
        function LoadregdataButtonPushed(app, event)
            f_dv_load_reg_data(app);
        end

        % Value changed function: RawButton
        function RawButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: CButton
        function CButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: SpikesButton
        function SpikesButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: StimtimesButton
        function StimtimesButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: trialtypeDropDown
        function trialtypeDropDownValueChanged(app, event)
            f_dv_update_cell(app);
            if app.UpdatefigsCheckBox.Value
                if isgraphics(app.gui_plots.select_resp_fig)
                    f_dv_plot_select_trial(app)
                end
            end
            f_dv_set_contorus(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            f_dv_update_cell(app);
            f_dv_set_contorus(app)
        end

        % Button pushed function: PlotfreqrespButton
        function PlotfreqrespButtonPushed(app, event)
            f_dv_plot_freq_resp(app);
        end

        % Button pushed function: PlotctxrespButton
        function PlotctxrespButtonPushed(app, event)
            f_dv_plot_ctx_resp(app);
        end

        % Button pushed function: PlotselecttrialButton
        function PlotselecttrialButtonPushed(app, event)
            f_dv_plot_select_trial(app);
        end

        % Value changed function: SmoothCheckBox
        function SmoothCheckBoxValueChanged(app, event)
            f_dv_update_current_dset_data(app);
            f_dv_update_cell(app);
        end

        % Value changed function: DeconvolutionmethodDropDown
        function DeconvolutionmethodDropDownValueChanged(app, event)
            f_dv_update_current_dset_data(app);
            f_dv_update_cell(app);
        end

        % Value changed function: ManualtimewinCheckBox
        function ManualtimewinCheckBoxValueChanged(app, event)
            f_dv_load_ops(app);
            f_dv_update_dset_info(app);
        end

        % Value changed function: baserespsEditField
        function baserespsEditFieldValueChanged(app, event)
            f_dv_load_ops(app);
            f_dv_update_dset_info(app); 
        end

        % Value changed function: StatsourceDropDown
        function StatsourceDropDownValueChanged(app, event)
            f_dv_update_dset_info(app); 
        end

        % Value changed function: StatmethodDropDown
        function StatmethodDropDownValueChanged(app, event)
            f_dv_update_dset_info(app);
        end

        % Button pushed function: recomputestatsButton
        function recomputestatsButtonPushed(app, event)
            params = f_dv_gather_params(app);
            params.cdata = app.cdata;
            app.data(app.current_data_idx,:).stats{1}{params.n_pl} = f_dv_compute_stats(app,params);
            f_dv_update_dset_info(app);
            f_dv_initialize_contours(app);
            f_dv_set_contorus(app);
        end

        % Selection changed function: ContoursButtonGroup
        function ContoursButtonGroupSelectionChanged(app, event)
            f_dv_set_contorus(app);
        end

        % Value changed function: ContourMinEditField
        function ContourMinEditFieldValueChanged(app, event)
            temp_val = app.ContourMinEditField.Value;
            if temp_val < app.gui_ops.contour_params.c_abs_lim(1)
                app.ContourMinEditField.Value = app.gui_ops.contour_params.c_abs_lim(1);
            elseif temp_val > app.ContourMaxEditField.Value
                app.ContourMinEditField.Value = app.ContourMaxEditField.Value;
            end
            app.gui_ops.contour_params.c_lim(1) = app.ContourMinEditField.Value;
            f_dv_update_contour_lims(app)
            f_dv_set_contorus(app)
        end

        % Value changed function: ContourMaxEditField
        function ContourMaxEditFieldValueChanged(app, event)
            temp_val = app.ContourMaxEditField.Value;
            if temp_val > app.gui_ops.contour_params.c_abs_lim(2)
                app.ContourMaxEditField.Value = app.gui_ops.contour_params.c_abs_lim(2);
            elseif temp_val < app.ContourMinEditField.Value
                app.ContourMaxEditField.Value = app.ContourMinEditField.Value;
            end
            app.gui_ops.contour_params.c_lim(2) = app.ContourMaxEditField.Value;
            f_dv_update_contour_lims(app)
            f_dv_set_contorus(app)
        end

        % Button pushed function: RunallstatsButton
        function RunallstatsButtonPushed(app, event)
            f_dv_run_all_stats(app);
        end

        % Value changed function: RectifyspikesCheckBox
        function RectifyspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_dset_data(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: SubtractmeanspikesCheckBox
        function SubtractmeanspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_dset_data(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: NormalizemaxspikesCheckBox
        function NormalizemaxspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_dset_data(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: ConverttoZCheckBox
        function ConverttoZCheckBoxValueChanged(app, event)
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Button pushed function: PlottuningecdfButton
        function PlottuningecdfButtonPushed(app, event)
            f_dv_plot_tunning_ecdf(app);
        end

        % Button pushed function: PlotregButton
        function PlotregButtonPushed(app, event)
            f_dv_plot_reg(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'A')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Trace')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ContoursButtonGroup = uibuttongroup(app.UIFigure);
            app.ContoursButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ContoursButtonGroupSelectionChanged, true);
            app.ContoursButtonGroup.Title = 'Contours';
            app.NoneButton = uitogglebutton(app.ContoursButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.TuningtypeButton = uitogglebutton(app.ContoursButtonGroup);
            app.TuningtypeButton.Text = 'Tuning type';
            app.TuningmagButton = uitogglebutton(app.ContoursButtonGroup);
            app.TuningmagButton.Text = 'Tuning mag';
            app.OnsetoffsetButton = uitogglebutton(app.ContoursButtonGroup);
            app.OnsetoffsetButton.Text = 'Onset offset';
            app.SNRButton_5 = uitogglebutton(app.ContoursButtonGroup);
            app.SNRButton_5.Text = 'SNR';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'Min';
            app.ContourMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourMinEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMinEditFieldValueChanged, true);
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max';
            app.ContourMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMaxEditFieldValueChanged, true);
            app.ContourlimitsLabel = uilabel(app.UIFigure);
            app.ContourlimitsLabel.Text = 'Contour limits';
            app.UIAxesColorPallet = uiaxes(app.UIFigure);
            xlabel(app.UIAxesColorPallet, 'Contour color')
            app.UIAxesColorPallet.YTick = [];
            app.UIAxesColorPallet.YTickLabel = '';
            app.Runs31Button = uibutton(app.UIFigure, 'push');
            app.Runs31Button.ButtonPushedFcn = createCallbackFcn(app, @Runs31ButtonPushed, true);
            app.Runs31Button.Text = 'Run s31';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = {};
            app.mplSpinnerLabel = uilabel(app.UIFigure);
            app.mplSpinnerLabel.Text = 'mpl';
            app.mplSpinner = uispinner(app.UIFigure);
            app.mplSpinner.Limits = [1 Inf];
            app.mplSpinner.RoundFractionalValues = 'on';
            app.mplSpinner.ValueChangedFcn = createCallbackFcn(app, @mplSpinnerValueChanged, true);
            app.mplSpinner.Value = 1;
            app.CellSpinnerLabel = uilabel(app.UIFigure);
            app.CellSpinnerLabel.Text = 'Cell';
            app.CellSpinner = uispinner(app.UIFigure);
            app.CellSpinner.Limits = [1 Inf];
            app.CellSpinner.RoundFractionalValues = 'on';
            app.CellSpinner.ValueChangedFcn = createCallbackFcn(app, @CellSpinnerValueChanged, true);
            app.CellSpinner.Value = 1;
            app.RawButton = uibutton(app.UIFigure, 'state');
            app.RawButton.ValueChangedFcn = createCallbackFcn(app, @RawButtonValueChanged, true);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.SpikesButton = uibutton(app.UIFigure, 'state');
            app.SpikesButton.ValueChangedFcn = createCallbackFcn(app, @SpikesButtonValueChanged, true);
            app.SpikesButton.Text = 'Spikes';
            app.regdatapathEditFieldLabel = uilabel(app.UIFigure);
            app.regdatapathEditFieldLabel.Text = 'reg data path';
            app.regdatapathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadregdataButton = uibutton(app.UIFigure, 'push');
            app.LoadregdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadregdataButtonPushed, true);
            app.LoadregdataButton.Text = 'Load reg data';
            app.StimtimesButton = uibutton(app.UIFigure, 'state');
            app.StimtimesButton.ValueChangedFcn = createCallbackFcn(app, @StimtimesButtonValueChanged, true);
            app.StimtimesButton.Text = 'Stim times';
            app.trialtypeDropDownLabel = uilabel(app.UIFigure);
            app.trialtypeDropDownLabel.Text = 'trial type';
            app.trialtypeDropDown = uidropdown(app.UIFigure);
            app.trialtypeDropDown.Items = {};
            app.trialtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @trialtypeDropDownValueChanged, true);
            app.trialtypeDropDown.Value = {};
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField_sp = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_sp.Value = 1;
            app.ShiftEditFieldLabel = uilabel(app.UIFigure);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField_sp = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_3Label = uilabel(app.UIFigure);
            app.ScaleEditField_3Label.Text = 'Scale';
            app.ScaleEditField_st = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_st.Value = 1;
            app.ShiftEditField_3Label = uilabel(app.UIFigure);
            app.ShiftEditField_3Label.Text = 'Shift';
            app.ShiftEditField_st = uieditfield(app.UIFigure, 'numeric');
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CButton = uibutton(app.UIFigure, 'state');
            app.CButton.ValueChangedFcn = createCallbackFcn(app, @CButtonValueChanged, true);
            app.CButton.Text = 'C';
            app.DeconvolutionmethodDropDownLabel = uilabel(app.UIFigure);
            app.DeconvolutionmethodDropDownLabel.Text = 'Deconvolution method';
            app.DeconvolutionmethodDropDown = uidropdown(app.UIFigure);
            app.DeconvolutionmethodDropDown.Items = {};
            app.DeconvolutionmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DeconvolutionmethodDropDownValueChanged, true);
            app.DeconvolutionmethodDropDown.Value = {};
            app.SmoothCheckBox = uicheckbox(app.UIFigure);
            app.SmoothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckBoxValueChanged, true);
            app.SmoothCheckBox.Text = 'Smooth';
            app.SmoothCheckBox.Value = true;
            app.SmoothsigmamsEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothsigmamsEditFieldLabel.Text = 'Smooth sigma (ms)';
            app.SmoothsigmamsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothsigmamsEditField.Value = 150;
            app.UpdatefigsCheckBox = uicheckbox(app.UIFigure);
            app.UpdatefigsCheckBox.Text = 'Update figs';
            app.UpdatefigsCheckBox.Value = true;
            app.RunallstatsButton = uibutton(app.UIFigure, 'push');
            app.RunallstatsButton.ButtonPushedFcn = createCallbackFcn(app, @RunallstatsButtonPushed, true);
            app.RunallstatsButton.Text = 'Run all stats';
            app.PlotfreqrespButton = uibutton(app.UIFigure, 'push');
            app.PlotfreqrespButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfreqrespButtonPushed, true);
            app.PlotfreqrespButton.Text = 'Plot freq resp';
            app.PlotctxrespButton = uibutton(app.UIFigure, 'push');
            app.PlotctxrespButton.ButtonPushedFcn = createCallbackFcn(app, @PlotctxrespButtonPushed, true);
            app.PlotctxrespButton.Text = 'Plot ctx resp';
            app.FullredCheckBox = uicheckbox(app.UIFigure);
            app.FullredCheckBox.Text = 'Full red';
            app.ManualtimewinCheckBox = uicheckbox(app.UIFigure);
            app.ManualtimewinCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualtimewinCheckBoxValueChanged, true);
            app.ManualtimewinCheckBox.Text = 'Manual time win';
            app.NewplotsCheckBox = uicheckbox(app.UIFigure);
            app.NewplotsCheckBox.Text = 'New plots';
            app.PlotselecttrialButton = uibutton(app.UIFigure, 'push');
            app.PlotselecttrialButton.ButtonPushedFcn = createCallbackFcn(app, @PlotselecttrialButtonPushed, true);
            app.PlotselecttrialButton.Text = 'Plot select trial';
            app.recomputestatsButton = uibutton(app.UIFigure, 'push');
            app.recomputestatsButton.ButtonPushedFcn = createCallbackFcn(app, @recomputestatsButtonPushed, true);
            app.recomputestatsButton.Text = 'recompute stats';
            app.NormalizemaxspikesCheckBox = uicheckbox(app.UIFigure);
            app.NormalizemaxspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizemaxspikesCheckBoxValueChanged, true);
            app.NormalizemaxspikesCheckBox.Text = 'Normalize max spikes';
            app.NormalizemaxspikesCheckBox.Value = true;
            app.ConverttoZCheckBox = uicheckbox(app.UIFigure);
            app.ConverttoZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoZCheckBoxValueChanged, true);
            app.ConverttoZCheckBox.Text = 'Convert to Z';
            app.ConverttoZCheckBox.Value = true;
            app.SubtractmeanspikesCheckBox = uicheckbox(app.UIFigure);
            app.SubtractmeanspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractmeanspikesCheckBoxValueChanged, true);
            app.SubtractmeanspikesCheckBox.Text = 'Subtract mean spikes';
            app.SubtractmeanspikesCheckBox.Value = true;
            app.RectifyspikesCheckBox = uicheckbox(app.UIFigure);
            app.RectifyspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectifyspikesCheckBoxValueChanged, true);
            app.RectifyspikesCheckBox.Text = 'Rectify spikes';
            app.RectifyspikesCheckBox.Value = true;
            app.PlottuningecdfButton = uibutton(app.UIFigure, 'push');
            app.PlottuningecdfButton.ButtonPushedFcn = createCallbackFcn(app, @PlottuningecdfButtonPushed, true);
            app.PlottuningecdfButton.Text = 'Plot tuning ecdf';
            app.CombinegroupsCheckBox = uicheckbox(app.UIFigure);
            app.CombinegroupsCheckBox.Text = 'Combine groups ';
            app.PlotregButton = uibutton(app.UIFigure, 'push');
            app.PlotregButton.ButtonPushedFcn = createCallbackFcn(app, @PlotregButtonPushed, true);
            app.PlotregButton.Text = 'Plot reg';
            app.SNREditFieldLabel = uilabel(app.UIFigure);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditField.Editable = 'off';
            app.MMNfreqEditFieldLabel = uilabel(app.UIFigure);
            app.MMNfreqEditFieldLabel.Text = 'MMN freq';
            app.MMNfreqEditField = uieditfield(app.UIFigure, 'text');
            app.MMNfreqEditField.Editable = 'off';
            app.baserespsLabel = uilabel(app.UIFigure);
            app.baserespsLabel.Text = '[base, resp] (s)';
            app.baserespsEditField = uieditfield(app.UIFigure, 'text');
            app.baserespsEditField.ValueChangedFcn = createCallbackFcn(app, @baserespsEditFieldValueChanged, true);
            app.baserespsEditField.Value = '-.05, .95';
            app.FramerateEditFieldLabel = uilabel(app.UIFigure);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramerateEditField.Editable = 'off';
            app.StatsourceDropDownLabel = uilabel(app.UIFigure);
            app.StatsourceDropDownLabel.Text = 'Stat source';
            app.StatsourceDropDown = uidropdown(app.UIFigure);
            app.StatsourceDropDown.Items = {};
            app.StatsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @StatsourceDropDownValueChanged, true);
            app.StatsourceDropDown.Value = {};
            app.StatmethodDropDownLabel = uilabel(app.UIFigure);
            app.StatmethodDropDownLabel.Text = 'Stat method';
            app.StatmethodDropDown = uidropdown(app.UIFigure);
            app.StatmethodDropDown.Items = {};
            app.StatmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @StatmethodDropDownValueChanged, true);
            app.StatmethodDropDown.Value = {};
            app.ZthreshcurrentEditFieldLabel = uilabel(app.UIFigure);
            app.ZthreshcurrentEditFieldLabel.Text = 'Z thresh current';
            app.ZthreshcurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZthreshcurrentEditField.Editable = 'off';
            app.ZthreshnewEditFieldLabel = uilabel(app.UIFigure);
            app.ZthreshnewEditFieldLabel.Text = 'Z thresh new';
            app.ZthreshnewEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZthreshnewEditField.Value = 3;
            app.TuningecdfthreshEditFieldLabel = uilabel(app.UIFigure);
            app.TuningecdfthreshEditFieldLabel.Text = 'Tuning ecdf thresh';
            app.TuningecdfthreshEditField = uieditfield(app.UIFigure, 'numeric');
            app.plotdataButtonGroup = uibuttongroup(app.UIFigure);
            app.plotdataButtonGroup.Title = 'plot data';
            app.dsetButton = uitogglebutton(app.plotdataButtonGroup);
            app.dsetButton.Text = 'dset';
            app.dsetButton.Value = true;
            app.mouseButton = uitogglebutton(app.plotdataButtonGroup);
            app.mouseButton.Text = 'mouse';
            app.allButton = uitogglebutton(app.plotdataButtonGroup);
            app.allButton.Text = 'all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dset_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end